classdef Seleccionador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AvisosPanel                    matlab.ui.container.Panel
        AvisoLabel                     matlab.ui.control.Label
        SeleccionarvaloresPanel        matlab.ui.container.Panel
        MultiplosLabel                 matlab.ui.control.Label
        TiempoTotalmsEditField         matlab.ui.control.NumericEditField
        TiempoTotalmsEditFieldLabel    matlab.ui.control.Label
        RuidodBSlider                  matlab.ui.control.Slider
        RuidodBLabel                   matlab.ui.control.Label
        CorrientegatilloSlider         matlab.ui.control.Slider
        CorrientegatilloSliderLabel    matlab.ui.control.Label
        TiempodeBarridoKnob            matlab.ui.control.DiscreteKnob
        TiempodeBarridoKnobLabel       matlab.ui.control.Label
        SeleccionadordeNeuronasPanel   matlab.ui.container.Panel
        ConteoInhLabel                 matlab.ui.control.Label
        ConteoTotalLabel               matlab.ui.control.Label
        RestaurarpredeterminadaButton  matlab.ui.control.Button
        CapturarButton                 matlab.ui.control.Button
        NdeNeuronasinhibidorasLabel    matlab.ui.control.Label
        NdeneuronasLabel               matlab.ui.control.Label
        EditFieldClass2excInhibidoras  matlab.ui.control.NumericEditField
        EditFieldClass1excInhibidoras  matlab.ui.control.NumericEditField
        EditFieldSpkieFreqAdaptInhibidoras  matlab.ui.control.NumericEditField
        EditFieldMixedModeInhibidoras  matlab.ui.control.NumericEditField
        EditFieldTonicBurstingInhibidoras  matlab.ui.control.NumericEditField
        EditFieldTonicSpikingaTonicSpikingInhibidoras  matlab.ui.control.NumericEditField
        EditFieldSpkieClass2excDeNeuronas  matlab.ui.control.NumericEditField
        EditFieldSpkieClass1excDeNeuronas  matlab.ui.control.NumericEditField
        EditFieldSpkieFreqAdaptNumeroDeNeuronas  matlab.ui.control.NumericEditField
        EditFieldMixedModeNumeroDeNeuronas  matlab.ui.control.NumericEditField
        EditFieldTonicBurstingNumeroDeNeuronas  matlab.ui.control.NumericEditField
        EditFieldTonicSpikingNumeroDeNeuronas  matlab.ui.control.NumericEditField
        AutomatizarinhibicinCheckBox   matlab.ui.control.CheckBox
        Class2excCheckBox              matlab.ui.control.CheckBox
        Class1excCheckBox              matlab.ui.control.CheckBox
        SpikeFreqAdaptCheckBox         matlab.ui.control.CheckBox
        MixedModeCheckBox              matlab.ui.control.CheckBox
        TonicBurstingCheckBox          matlab.ui.control.CheckBox
        TonicSpikingCheckBox           matlab.ui.control.CheckBox
    end

properties (Access = private)
         catalogo
         carpeta
end 
    methods (Access = private)
                % prende o apaga cada fila segun que neurona 
        function habilitarFilas(app)
            if app.AutomatizarinhibicinCheckBox.Value
                lasInhibidorasApagadasOEncendidas = 'off';
            else
                lasInhibidorasApagadasOEncendidas = 'on';
            end
            if app.TonicSpikingCheckBox.Value
                app.EditFieldTonicSpikingNumeroDeNeuronas.Enable = 'on';
                app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldTonicSpikingNumeroDeNeuronas.Enable = 'off';
                app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Enable = 'off';
            end
            if app.TonicBurstingCheckBox.Value
                app.EditFieldTonicBurstingNumeroDeNeuronas.Enable = 'on';
                app.EditFieldTonicBurstingInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldTonicBurstingNumeroDeNeuronas.Enable = 'off';
                app.EditFieldTonicBurstingInhibidoras.Enable = 'off';
            end
            if app.MixedModeCheckBox.Value
                app.EditFieldMixedModeNumeroDeNeuronas.Enable = 'on';
                app.EditFieldMixedModeInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldMixedModeNumeroDeNeuronas.Enable = 'off';
                app.EditFieldMixedModeInhibidoras.Enable = 'off';
            end
            if app.SpikeFreqAdaptCheckBox.Value
                app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Enable = 'on';
                app.EditFieldSpkieFreqAdaptInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Enable = 'off';
                app.EditFieldSpkieFreqAdaptInhibidoras.Enable = 'off';
            end
            if app.Class1excCheckBox.Value
                app.EditFieldSpkieClass1excDeNeuronas.Enable = 'on';
                app.EditFieldClass1excInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldSpkieClass1excDeNeuronas.Enable = 'off';
                app.EditFieldClass1excInhibidoras.Enable = 'off';
            end
            if app.Class2excCheckBox.Value
                app.EditFieldSpkieClass2excDeNeuronas.Enable = 'on';
                app.EditFieldClass2excInhibidoras.Enable = lasInhibidorasApagadasOEncendidas;
            else
                app.EditFieldSpkieClass2excDeNeuronas.Enable = 'off';
                app.EditFieldClass2excInhibidoras.Enable = 'off';
            end
        end
        % elige 20 de las 100 neuronas al azar y cuenta cuantas tocan a cada tipo
        function repartirAlAzar(app)
            cant = [app.EditFieldTonicSpikingNumeroDeNeuronas.Value, app.EditFieldTonicBurstingNumeroDeNeuronas.Value,  app.EditFieldMixedModeNumeroDeNeuronas.Value, app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Value, app.EditFieldSpkieClass1excDeNeuronas.Value, app.EditFieldSpkieClass2excDeNeuronas.Value];
            inhibidoras = zeros(1, 6);
            if sum(cant) == 100
                % tiene el tipo de cada neurona 
                lista = [];
                for k = 1:6
                    lista = [lista, k * ones(1, cant(k))];
                end
                elegidas = lista(randperm(100, 20));
                for k = 1:6
                    inhibidoras(k) = sum(elegidas == k);
                end
            end
            app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Value = inhibidoras(1);
            app.EditFieldTonicBurstingInhibidoras.Value = inhibidoras(2);
            app.EditFieldMixedModeInhibidoras.Value = inhibidoras(3);
            app.EditFieldSpkieFreqAdaptInhibidoras.Value = inhibidoras(4);
            app.EditFieldClass1excInhibidoras.Value = inhibidoras(5);
            app.EditFieldClass2excInhibidoras.Value = inhibidoras(6);
        end
        function actualizarConteo(app)
            cant = [app.EditFieldTonicSpikingNumeroDeNeuronas.Value, app.EditFieldTonicBurstingNumeroDeNeuronas.Value, app.EditFieldMixedModeNumeroDeNeuronas.Value, app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Value, app.EditFieldSpkieClass1excDeNeuronas.Value, app.EditFieldSpkieClass2excDeNeuronas.Value];
            inh = [app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Value, app.EditFieldTonicBurstingInhibidoras.Value, app.EditFieldMixedModeInhibidoras.Value, app.EditFieldSpkieFreqAdaptInhibidoras.Value, app.EditFieldClass1excInhibidoras.Value, app.EditFieldClass2excInhibidoras.Value];
            verde = [0 0.6 0];
            rojo = [0.85 0 0];
            app.ConteoTotalLabel.Text = sprintf('Total: %d / 100', sum(cant));
            if sum(cant) == 100
                app.ConteoTotalLabel.FontColor = verde;
            else
                app.ConteoTotalLabel.FontColor = rojo;
            end
            app.ConteoInhLabel.Text = sprintf('Inhibidoras: %d / 20', sum(inh));
            if sum(inh) == 20 && ~any(inh > cant)
                app.ConteoInhLabel.FontColor = verde;
            else
                app.ConteoInhLabel.FontColor = rojo;
            end
            aviso = '';
            if any(inh > cant)
                aviso = [aviso, 'Hay tipos con mas inhibidoras que neuronas. '];
            end
            if app.AutomatizarinhibicinCheckBox.Value && sum(cant) ~= 100
                aviso = [aviso, 'Las inhibidoras automaticas se reparten cuando el total sea 100. '];
            end
            barrido = str2double(app.TiempodeBarridoKnob.Value);
            % la rampa con menos barrido solo se usa el inicio
            if (app.Class1excCheckBox.Value || app.Class2excCheckBox.Value) && barrido < 200
                aviso = [aviso, 'Con barrido menor a 200 ms solo se usa el inicio de la rampa de corriente'];
            end
            app.AvisoLabel.Text = aviso;
        end
        function cargarConfiguracion(app, cant, inh, automatico)
            app.TonicSpikingCheckBox.Value = cant(1) > 0;
            app.TonicBurstingCheckBox.Value = cant(2) > 0;
            app.MixedModeCheckBox.Value = cant(3) > 0;
            app.SpikeFreqAdaptCheckBox.Value = cant(4) > 0;
            app.Class1excCheckBox.Value = cant(5) > 0;
            app.Class2excCheckBox.Value = cant(6) > 0;
            app.EditFieldTonicSpikingNumeroDeNeuronas.Value = cant(1);
            app.EditFieldTonicBurstingNumeroDeNeuronas.Value = cant(2);
            app.EditFieldMixedModeNumeroDeNeuronas.Value = cant(3);
            app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Value = cant(4);
            app.EditFieldSpkieClass1excDeNeuronas.Value = cant(5);
            app.EditFieldSpkieClass2excDeNeuronas.Value = cant(6);
            app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Value = inh(1);
            app.EditFieldTonicBurstingInhibidoras.Value = inh(2);
            app.EditFieldMixedModeInhibidoras.Value = inh(3);
            app.EditFieldSpkieFreqAdaptInhibidoras.Value = inh(4);
            app.EditFieldClass1excInhibidoras.Value = inh(5);
            app.EditFieldClass2excInhibidoras.Value = inh(6);
            app.AutomatizarinhibicinCheckBox.Value = logical(automatico);
            habilitarFilas(app);
            actualizarConteo(app);
        end
        % junta los errores en un solo aviso y guarda
        function ok = func(app)
            ok = false;
            tiempo = app.TiempoTotalmsEditField.Value;
            barrido = str2double(app.TiempodeBarridoKnob.Value);
            dB = app.RuidodBSlider.Value;
            gatillo = app.CorrientegatilloSlider.Value;
            automatico = app.AutomatizarinhibicinCheckBox.Value;
            cantidades = [app.EditFieldTonicSpikingNumeroDeNeuronas.Value, app.EditFieldTonicBurstingNumeroDeNeuronas.Value, app.EditFieldMixedModeNumeroDeNeuronas.Value, app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Value, app.EditFieldSpkieClass1excDeNeuronas.Value, app.EditFieldSpkieClass2excDeNeuronas.Value];
            inhibitorias = [app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Value, app.EditFieldTonicBurstingInhibidoras.Value, app.EditFieldMixedModeInhibidoras.Value, app.EditFieldSpkieFreqAdaptInhibidoras.Value, app.EditFieldClass1excInhibidoras.Value, app.EditFieldClass2excInhibidoras.Value];
            errores = {};
            if any(mod(cantidades, 1) ~= 0) || any(mod(inhibitorias, 1) ~= 0)
                errores{end+1} = ' Las cantidades de neuronas deben ser numeros enteros. ';
            end
            if tiempo <= 599
                errores{end+1} = ' El tiempo total debe ser mayor a 599 ms. ';
            end
            if mod(tiempo, barrido) ~= 0
                errores{end+1} = sprintf(' El tiempo total debe ser multiplo de %d ms. ', barrido);
            end
            if gatillo <= 0
                errores{end+1} = ' La corriente gatillo debe ser mayor a 0. ';
            end
            if sum(cantidades > 0) < 3
                errores{end+1} = ' Debe haber al menos 3 tipos de neurona con neuronas asignadas. ';
            end
            if sum(cantidades) ~= 100
                errores{end+1} = sprintf(' Debe haber exactamente 100 neuronas (hay %d). ', sum(cantidades));
            end
            if sum(inhibitorias) ~= 20
                errores{end+1} = sprintf(' Debe haber exactamente 20 inhibidoras (hay %d). ', sum(inhibitorias));
            end
            if any(inhibitorias > cantidades)
                errores{end+1} = ' Ningun tipo puede tener mas inhibidoras que neuronas. ';
            end

            if ~isempty(errores)
                uialert(app.UIFigure, strjoin(errores, newline), 'Advertencia');
                return;
            end
           nombres = {app.TonicSpikingCheckBox.Text, app.TonicBurstingCheckBox.Text, app.MixedModeCheckBox.Text, app.SpikeFreqAdaptCheckBox.Text, app.Class1excCheckBox.Text, app.Class2excCheckBox.Text};
            % si hay catalogo se usan sus nombres, que son los que compara neurona()
            if numel(app.catalogo) == 6
                for k = 1:6
                    nombres{k} = char(app.catalogo(k).nombre);
                end
            end
            excitatorias = cantidades - inhibitorias;
            save(fullfile(app.carpeta, 'datosCapturados.mat'), 'tiempo', 'barrido', 'dB','gatillo', 'nombres', 'cantidades', 'inhibitorias', 'excitatorias', 'automatico');
            uialert(app.UIFigure, 'Datos guardados en datosCapturados.mat', 'Captura exitosa', 'Icon', 'success');
            ok = true;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.carpeta = fileparts(which('Seleccionador'));
            if isempty(app.carpeta)
                app.carpeta = pwd;
            end
            archivoCat = fullfile(app.carpeta, 'catalogo.mat');
            if isfile(archivoCat)
                datos = load(archivoCat, 'catalogo');
                app.catalogo = datos.catalogo;
            else
                uialert(app.UIFigure, 'No se encontro catalogo.mat', 'Advertencia');   % en startupFcn
            end
            % usa la configuracion anterior, si no puede usa la predeterminada
            archivoCfg = fullfile(app.carpeta, 'datosCapturados.mat');
            if isfile(archivoCfg)
                cfg = load(archivoCfg);
                if isfield(cfg, 'cantidades') && isfield(cfg, 'inhibitorias') && isfield(cfg, 'automatico')
                    app.TiempoTotalmsEditField.Value = cfg.tiempo;
                    app.TiempodeBarridoKnob.Value = num2str(cfg.barrido);
                    app.RuidodBSlider.Value = cfg.dB;
                    app.CorrientegatilloSlider.Value = cfg.gatillo;
                    cargarConfiguracion(app, cfg.cantidades, cfg.inhibitorias, cfg.automatico);
                    TiempodeBarridoKnobValueChanged(app, []);
                else
                    RestaurarpredeterminadaButtonPushed(app, []);
                end
            else
                RestaurarpredeterminadaButtonPushed(app, []);
            end
        end

        % Value changed function: TonicSpikingCheckBox
        function TonicSpikingCheckBoxValueChanged(app, event)
            if ~app.TonicSpikingCheckBox.Value
                app.EditFieldTonicSpikingNumeroDeNeuronas.Value = 0;
                app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: TonicBurstingCheckBox
        function TonicBurstingCheckBoxValueChanged(app, event)
            if ~app.TonicBurstingCheckBox.Value
                app.EditFieldTonicBurstingNumeroDeNeuronas.Value = 0;
                app.EditFieldTonicBurstingInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: MixedModeCheckBox
        function MixedModeCheckBoxValueChanged(app, event)
            if ~app.MixedModeCheckBox.Value
                app.EditFieldMixedModeNumeroDeNeuronas.Value = 0;
                app.EditFieldMixedModeInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: SpikeFreqAdaptCheckBox
        function SpikeFreqAdaptCheckBoxValueChanged(app, event)
            if ~app.SpikeFreqAdaptCheckBox.Value
                app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Value = 0;
                app.EditFieldSpkieFreqAdaptInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: Class1excCheckBox
        function Class1excCheckBoxValueChanged(app, event)
            if ~app.Class1excCheckBox.Value
                app.EditFieldSpkieClass1excDeNeuronas.Value = 0;
                app.EditFieldClass1excInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: Class2excCheckBox
        function Class2excCheckBoxValueChanged(app, event)
            if ~app.Class2excCheckBox.Value
                app.EditFieldSpkieClass2excDeNeuronas.Value = 0;
                app.EditFieldClass2excInhibidoras.Value = 0;
            end
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: AutomatizarinhibicinCheckBox
        function AutomatizarinhibicinCheckBoxValueChanged(app, event)
            habilitarFilas(app);
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldTonicSpikingNumeroDeNeuronas
        function EditFieldTonicSpikingNumeroDeNeuronasValueChanged(app, event)
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldTonicBurstingNumeroDeNeuronas
        function EditFieldTonicBurstingNumeroDeNeuronasValueChanged(app, event)
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: TiempoTotalmsEditField
        function TiempoTotalmsEditFieldValueChanged(app, event)
            barrido = str2double(app.TiempodeBarridoKnob.Value);
            tiempo = app.TiempoTotalmsEditField.Value;
            app.MultiplosLabel.Text = sprintf('Solo multiplos de %d', barrido);
            if mod(tiempo, barrido) == 0
                app.MultiplosLabel.FontColor = [0 0 0];
            else
                app.MultiplosLabel.FontColor = [0.85 0 0];
            end
            actualizarConteo(app);
        end

        % Value changed function: TiempodeBarridoKnob
        function TiempodeBarridoKnobValueChanged(app, event)
            barrido = str2double(app.TiempodeBarridoKnob.Value);
            tiempo = app.TiempoTotalmsEditField.Value;
            app.MultiplosLabel.Text = sprintf('Solo multiplos de %d', barrido);
            if mod(tiempo, barrido) == 0
                app.MultiplosLabel.FontColor = [0 0 0];
            else
                app.MultiplosLabel.FontColor = [0.85 0 0];
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldMixedModeNumeroDeNeuronas
        function EditFieldMixedModeNumeroDeNeuronasValueChanged(app, event)
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldSpkieFreqAdaptNumeroDeNeuronas
        function EditFieldSpkieFreqAdaptNumeroDeNeuronasValueChanged(app, event)
             if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldSpkieClass1excDeNeuronas
        function EditFieldSpkieClass1excDeNeuronasValueChanged(app, event)
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: EditFieldSpkieClass2excDeNeuronas
        function EditFieldSpkieClass2excDeNeuronasValueChanged(app, event)
            if app.AutomatizarinhibicinCheckBox.Value
                repartirAlAzar(app);
            end
            actualizarConteo(app);
        end

        % Value changed function: 
        % EditFieldTonicSpikingaTonicSpikingInhibidoras
        function EditFieldTonicSpikingaTonicSpikingInhibidorasValueChanged(app, event)
            actualizarConteo(app);
        end

        % Value changed function: EditFieldTonicBurstingInhibidoras
        function EditFieldTonicBurstingInhibidorasValueChanged(app, event)
             actualizarConteo(app);
        end

        % Value changed function: EditFieldMixedModeInhibidoras
        function EditFieldMixedModeInhibidorasValueChanged(app, event)
            actualizarConteo(app);
        end

        % Value changed function: EditFieldSpkieFreqAdaptInhibidoras
        function EditFieldSpkieFreqAdaptInhibidorasValueChanged(app, event)
            actualizarConteo(app);
        end

        % Value changed function: EditFieldClass1excInhibidoras
        function EditFieldClass1excInhibidorasValueChanged(app, event)
            actualizarConteo(app);
        end

        % Value changed function: EditFieldClass2excInhibidoras
        function EditFieldClass2excInhibidorasValueChanged(app, event)
            actualizarConteo(app);
        end

        % Button pushed function: CapturarButton
        function CapturarButtonPushed(app, event)
            check = func(app);
            if check
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: RestaurarpredeterminadaButton
        function RestaurarpredeterminadaButtonPushed(app, event)
            app.TiempoTotalmsEditField.Value = 5000;
            app.TiempodeBarridoKnob.Value    = '100';
            app.RuidodBSlider.Value          = 20;
            app.CorrientegatilloSlider.Value = 1;
            % valores predeterminados
            cantidades = [20 50 30 0 0 0];
            inhibitorias = zeros(1, 6);
            cargarConfiguracion(app, cantidades, inhibitorias, true);
            repartirAlAzar(app);
            TiempodeBarridoKnobValueChanged(app, []);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeleccionadordeNeuronasPanel = uipanel(app.UIFigure);
            app.SeleccionadordeNeuronasPanel.Title = 'Seleccionador de Neuronas';
            app.TonicSpikingCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.TonicSpikingCheckBox.ValueChangedFcn = createCallbackFcn(app, @TonicSpikingCheckBoxValueChanged, true);
            app.TonicSpikingCheckBox.Text = 'Tonic Spiking';
            app.TonicBurstingCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.TonicBurstingCheckBox.ValueChangedFcn = createCallbackFcn(app, @TonicBurstingCheckBoxValueChanged, true);
            app.TonicBurstingCheckBox.Text = 'Tonic Bursting';
            app.MixedModeCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.MixedModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MixedModeCheckBoxValueChanged, true);
            app.MixedModeCheckBox.Text = 'Mixed Mode';
            app.SpikeFreqAdaptCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.SpikeFreqAdaptCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpikeFreqAdaptCheckBoxValueChanged, true);
            app.SpikeFreqAdaptCheckBox.Text = 'Spike Freq Adapt';
            app.Class1excCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.Class1excCheckBox.ValueChangedFcn = createCallbackFcn(app, @Class1excCheckBoxValueChanged, true);
            app.Class1excCheckBox.Text = 'Class 1 exc';
            app.Class2excCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.Class2excCheckBox.ValueChangedFcn = createCallbackFcn(app, @Class2excCheckBoxValueChanged, true);
            app.Class2excCheckBox.Text = 'Class 2 exc';
            app.AutomatizarinhibicinCheckBox = uicheckbox(app.SeleccionadordeNeuronasPanel);
            app.AutomatizarinhibicinCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomatizarinhibicinCheckBoxValueChanged, true);
            app.AutomatizarinhibicinCheckBox.Text = 'Automatizar inhibición';
            app.EditFieldTonicSpikingNumeroDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldTonicSpikingNumeroDeNeuronas.Limits = [0 100];
            app.EditFieldTonicSpikingNumeroDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldTonicSpikingNumeroDeNeuronasValueChanged, true);
            app.EditFieldTonicBurstingNumeroDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldTonicBurstingNumeroDeNeuronas.Limits = [0 100];
            app.EditFieldTonicBurstingNumeroDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldTonicBurstingNumeroDeNeuronasValueChanged, true);
            app.EditFieldMixedModeNumeroDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldMixedModeNumeroDeNeuronas.Limits = [0 100];
            app.EditFieldMixedModeNumeroDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldMixedModeNumeroDeNeuronasValueChanged, true);
            app.EditFieldSpkieFreqAdaptNumeroDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.Limits = [0 100];
            app.EditFieldSpkieFreqAdaptNumeroDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldSpkieFreqAdaptNumeroDeNeuronasValueChanged, true);
            app.EditFieldSpkieClass1excDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldSpkieClass1excDeNeuronas.Limits = [0 100];
            app.EditFieldSpkieClass1excDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldSpkieClass1excDeNeuronasValueChanged, true);
            app.EditFieldSpkieClass2excDeNeuronas = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldSpkieClass2excDeNeuronas.Limits = [0 100];
            app.EditFieldSpkieClass2excDeNeuronas.ValueChangedFcn = createCallbackFcn(app, @EditFieldSpkieClass2excDeNeuronasValueChanged, true);
            app.EditFieldTonicSpikingaTonicSpikingInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldTonicSpikingaTonicSpikingInhibidoras.Limits = [0 100];
            app.EditFieldTonicSpikingaTonicSpikingInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldTonicSpikingaTonicSpikingInhibidorasValueChanged, true);
            app.EditFieldTonicBurstingInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldTonicBurstingInhibidoras.Limits = [0 100];
            app.EditFieldTonicBurstingInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldTonicBurstingInhibidorasValueChanged, true);
            app.EditFieldMixedModeInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldMixedModeInhibidoras.Limits = [0 100];
            app.EditFieldMixedModeInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldMixedModeInhibidorasValueChanged, true);
            app.EditFieldSpkieFreqAdaptInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldSpkieFreqAdaptInhibidoras.Limits = [0 100];
            app.EditFieldSpkieFreqAdaptInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldSpkieFreqAdaptInhibidorasValueChanged, true);
            app.EditFieldClass1excInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldClass1excInhibidoras.Limits = [0 100];
            app.EditFieldClass1excInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldClass1excInhibidorasValueChanged, true);
            app.EditFieldClass2excInhibidoras = uieditfield(app.SeleccionadordeNeuronasPanel, 'numeric');
            app.EditFieldClass2excInhibidoras.Limits = [0 100];
            app.EditFieldClass2excInhibidoras.ValueChangedFcn = createCallbackFcn(app, @EditFieldClass2excInhibidorasValueChanged, true);
            app.NdeneuronasLabel = uilabel(app.SeleccionadordeNeuronasPanel);
            app.NdeneuronasLabel.Text = 'N. de neuronas';
            app.NdeNeuronasinhibidorasLabel = uilabel(app.SeleccionadordeNeuronasPanel);
            app.NdeNeuronasinhibidorasLabel.Text = 'N. de Neuronas inhibidoras';
            app.CapturarButton = uibutton(app.SeleccionadordeNeuronasPanel, 'push');
            app.CapturarButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarButtonPushed, true);
            app.CapturarButton.Text = 'Capturar';
            app.RestaurarpredeterminadaButton = uibutton(app.SeleccionadordeNeuronasPanel, 'push');
            app.RestaurarpredeterminadaButton.ButtonPushedFcn = createCallbackFcn(app, @RestaurarpredeterminadaButtonPushed, true);
            app.RestaurarpredeterminadaButton.Text = 'Restaurar predeterminada';
            app.ConteoTotalLabel = uilabel(app.SeleccionadordeNeuronasPanel);
            app.ConteoTotalLabel.Text = 'Total: 0 / 100';
            app.ConteoInhLabel = uilabel(app.SeleccionadordeNeuronasPanel);
            app.ConteoInhLabel.Text = 'Inhibidoras: 0 / 20';
            app.SeleccionarvaloresPanel = uipanel(app.UIFigure);
            app.SeleccionarvaloresPanel.Title = 'Seleccionar valores';
            app.TiempodeBarridoKnobLabel = uilabel(app.SeleccionarvaloresPanel);
            app.TiempodeBarridoKnobLabel.Text = 'Tiempo de Barrido ';
            app.TiempodeBarridoKnob = uiknob(app.SeleccionarvaloresPanel, 'discrete');
            app.TiempodeBarridoKnob.Items = {'50', '100', '150', '200'};
            app.TiempodeBarridoKnob.ValueChangedFcn = createCallbackFcn(app, @TiempodeBarridoKnobValueChanged, true);
            app.TiempodeBarridoKnob.Value = '50';
            app.CorrientegatilloSliderLabel = uilabel(app.SeleccionarvaloresPanel);
            app.CorrientegatilloSliderLabel.Text = 'Corriente gatillo ';
            app.CorrientegatilloSlider = uislider(app.SeleccionarvaloresPanel);
            app.CorrientegatilloSlider.Limits = [0 1];
            app.CorrientegatilloSlider.Value = 0.1;
            app.RuidodBLabel = uilabel(app.SeleccionarvaloresPanel);
            app.RuidodBLabel.Text = 'Ruido (dB)    ';
            app.RuidodBSlider = uislider(app.SeleccionarvaloresPanel);
            app.RuidodBSlider.Limits = [0 60];
            app.TiempoTotalmsEditFieldLabel = uilabel(app.SeleccionarvaloresPanel);
            app.TiempoTotalmsEditFieldLabel.Text = 'Tiempo Total (ms)';
            app.TiempoTotalmsEditField = uieditfield(app.SeleccionarvaloresPanel, 'numeric');
            app.TiempoTotalmsEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoTotalmsEditFieldValueChanged, true);
            app.TiempoTotalmsEditField.Value = 1000;
            app.MultiplosLabel = uilabel(app.SeleccionarvaloresPanel);
            app.MultiplosLabel.Text = 'Solo múltiplos de 100';
            app.AvisosPanel = uipanel(app.UIFigure);
            app.AvisosPanel.Title = 'Avisos';
            app.AvisoLabel = uilabel(app.AvisosPanel);
            app.AvisoLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Seleccionador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end